classdef manualTargetAppear < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        LoadconfigfileMenu           matlab.ui.container.Menu
        SavecurrentconfigtofileMenu  matlab.ui.container.Menu
        changeTargetColorButton      matlab.ui.control.Button
        DisplayGraphSettingsPanel    matlab.ui.container.Panel
        YaxisLabel                   matlab.ui.control.Label
        XaxisLabel                   matlab.ui.control.Label
        MaxLabel                     matlab.ui.control.Label
        MinLabel                     matlab.ui.control.Label
        YaxisMaxEditField            matlab.ui.control.NumericEditField
        YaxisMinEditField            matlab.ui.control.NumericEditField
        XaxisMaxEditField            matlab.ui.control.NumericEditField
        XaxisMinEditField            matlab.ui.control.NumericEditField
        TargetToggleLabel            matlab.ui.control.Label
        ONOFFButton                  matlab.ui.control.Button
        ProgramModeButtonGroup       matlab.ui.container.ButtonGroup
        RunButton_2                  matlab.ui.control.ToggleButton
        ConfigureButton              matlab.ui.control.ToggleButton
        TargetPositionSettingsPanel  matlab.ui.container.Panel
        CenterYEditField             matlab.ui.control.NumericEditField
        CenterYEditFieldLabel        matlab.ui.control.Label
        CenterXEditField             matlab.ui.control.NumericEditField
        CenterXEditFieldLabel        matlab.ui.control.Label
        DiameterEditField            matlab.ui.control.NumericEditField
        DiameterEditFieldLabel       matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
       
        UIR;
        dispR; % object from the rectangle function for drawing circle
        
        
        % CirclePosistion Cpos: [D X Y] to have following fields: D - diamter of circle; X - x coordinate of center; Y - y coordinate of center
        Cpos;

        % Rectangle Position to have fields corresponding to [x y w h] of matlab's rectangle function, to be automatically derived from user input to the CirclePos property
        Rpos;
        
%         dispFigure; % figure object for the target display window
%         dispAxes; % axes object for the target display window
        targetColor = [1 0 0];
        targetVisible = true;
        
        DialogApp % dialog box app for color editing
        
end
    
    properties (Access = public)
        dispFigure; % figure object for the target display window
        dispAxes; % axes object for the target display window

        cfg % a struct holding all necessary configuration data
        cfgJSON % configuration data encoded as JSON text
        
    end
    
    methods (Access = private)
        
        function drawTargetInit(app)     
            % Draw target based on current configuration
            
            % First in the UIAxes
            app.UIAxes.XLim = [app.XaxisMinEditField.Value, ...
                app.XaxisMaxEditField.Value];
            app.UIAxes.YLim = [app.YaxisMinEditField.Value, ...
                app.YaxisMaxEditField.Value];
            axis(app.UIAxes, 'square');
            grid(app.UIAxes, 'on')
            app.UIAxes.Visible = 'on';
            position = app.Rpos; % format: [x y w h]
            app.UIR = rectangle(app.UIAxes, 'Position', position, ...
                'Curvature', [1 1], ...
                'FaceColor', app.targetColor, ...
                'EdgeColor', app.targetColor);
            
            % Next in the Display Figure
            app.dispAxes.XLim = [app.XaxisMinEditField.Value, ...
                app.XaxisMaxEditField.Value];
            app.dispAxes.YLim = [app.YaxisMinEditField.Value, ...
                app.YaxisMaxEditField.Value];
            axis(app.dispAxes, 'square');
            grid(app.dispAxes, 'on')
            app.dispAxes.Visible = 'on';
            position = app.Rpos; % format: [x y w h]
            app.dispR = rectangle(app.dispAxes, 'Position', position, ...
                'Curvature', [1 1], ...
                'FaceColor', app.targetColor, ...
                'EdgeColor', app.targetColor);
           
            
        end
        
        function drawTargetOff(app)
           app.UIR.FaceColor = 'none';
           app.UIR.EdgeColor = 'none';
           
           app.dispR.FaceColor = 'none';
           app.dispR.EdgeColor = 'none';
            
        end
        
        function drawTargetOn(app)
            app.UIR.FaceColor = app.targetColor;
            app.UIR.EdgeColor = app.targetColor;
            
            app.dispR.FaceColor = app.targetColor;
            app.dispR.EdgeColor = app.targetColor;
            
        end
        
        function eraseTarget(app)
            drawTargetOff(app);

            
        end
        
        function Cpos2Rpos(app)
            c = app.Cpos;
            app.Rpos =  [ (c(2)-(c(1)/2)), (c(3)-(c(1)/2)), c(1), c(1)]; % struct to have fields corresponding to [x y w h] of matlab's rectangle function, to be automatically derived from user input to the CirclePos property

            
        end
        
        function createTargetDisplay(app)
            close all
            name = 'targetDisplay';
            bckcolor = 'k';
            app.dispFigure = figure('Name', name, ...
                'Color', bckcolor);
            app.dispAxes = axes(app.dispFigure);
            
            % Hide menu bars
            set(app.dispFigure, 'MenuBar', 'none');
            set(app.dispFigure, 'ToolBar', 'none');
 
        end
        
        function updateTargetPos(app)
            % First in the UIAxes
            position = app.Rpos; % format: [x y w h]
            app.UIR.Position = position;
            app.dispR.Position = position;
            
        end
        
        function applyConfig(app)
            % update all constants and dependents
            app.Cpos(1) = app.cfg.Cpos.diameter; 
            app.Cpos(2) = app.cfg.Cpos.centerx;
            app.Cpos(3) = app.cfg.Cpos.centery;
                
            app.DiameterEditField.Value = app.cfg.Cpos.diameter; 
            app.CenterXEditField.Value  = app.cfg.Cpos.centerx;
            app.CenterYEditField.Value  = app.cfg.Cpos.centery;
            
            app.targetColor(1) = app.cfg.targetColor.R;
            app.targetColor(2) = app.cfg.targetColor.G;
            app.targetColor(3) = app.cfg.targetColor.B;
            app.XaxisMinEditField.Value = app.cfg.Axes.XLim.Min;
            app.XaxisMaxEditField.Value = app.cfg.Axes.XLim.Max;
            app.YaxisMinEditField.Value = app.cfg.Axes.YLim.Min;
            app.YaxisMaxEditField.Value = app.cfg.Axes.YLim.Max;
            
            app.UIFigure.Position(1) = app.cfg.UIFigure.Position.left;
            app.UIFigure.Position(2) = app.cfg.UIFigure.Position.bottom;
            app.UIFigure.Position(3) = app.cfg.UIFigure.Position.width;
            app.UIFigure.Position(4) = app.cfg.UIFigure.Position.height;
            
            app.dispFigure.Position(1) = app.cfg.dispFigure.Position.left;
            app.dispFigure.Position(2) = app.cfg.dispFigure.Position.bottom;
            app.dispFigure.Position(3) = app.cfg.dispFigure.Position.width;
            app.dispFigure.Position(4) = app.cfg.dispFigure.Position.height;
            
            Cpos2Rpos(app);           
            
        end
    end
    
    methods (Access = public)
        
        function updateTargetColor(app, mainColor)
            app.targetColor = mainColor;
            drawTargetOn(app);
            
            app.changeTargetColorButton.Enable = 'on';
            
        end
        
        function updateConfig(app)
            
            app.cfg.Cpos.diameter = app.Cpos(1);
            app.cfg.Cpos.centerx  = app.Cpos(2);
            app.cfg.Cpos.centery  = app.Cpos(3);
            
            app.cfg.targetColor.R = app.targetColor(1);
            app.cfg.targetColor.G = app.targetColor(2);
            app.cfg.targetColor.B = app.targetColor(3);
            app.cfg.Axes.XLim.Min = app.XaxisMinEditField.Value;
            app.cfg.Axes.XLim.Max = app.XaxisMaxEditField.Value;
            app.cfg.Axes.YLim.Min = app.YaxisMinEditField.Value;
            app.cfg.Axes.YLim.Max = app.YaxisMaxEditField.Value;
            
            app.cfg.UIFigure.Position.left   = app.UIFigure.Position(1);
            app.cfg.UIFigure.Position.bottom = app.UIFigure.Position(2);
            app.cfg.UIFigure.Position.width  = app.UIFigure.Position(3);
            app.cfg.UIFigure.Position.height = app.UIFigure.Position(4);
            
            app.cfg.dispFigure.Position.left   = app.dispFigure.Position(1);
            app.cfg.dispFigure.Position.bottom = app.dispFigure.Position(2);
            app.cfg.dispFigure.Position.width  = app.dispFigure.Position(3);
            app.cfg.dispFigure.Position.height = app.dispFigure.Position(4);
            
                        
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Get default values based on button edit fields
            app.Cpos = [app.DiameterEditField.Value, ...
                app.CenterXEditField.Value, ...
                app.CenterYEditField.Value];
            Cpos2Rpos(app);
            
            createTargetDisplay(app);
            app.targetVisible = true;
            drawTargetInit(app);

        end

        % Value changed function: DiameterEditField
        function DiameterEditFieldValueChanged(app, event)
            % Update Cpos and Rpos accordingly
            app.Cpos(1) = app.DiameterEditField.Value;
            Cpos2Rpos(app);
            updateTargetPos(app);
            
        end

        % Value changed function: CenterXEditField
        function CenterXEditFieldValueChanged(app, event)
            % Update Cpos and Rpos accordingly
            app.Cpos(2) = app.CenterXEditField.Value;
            Cpos2Rpos(app);
            updateTargetPos(app);

            
        end

        % Value changed function: CenterYEditField
        function CenterYEditFieldValueChanged(app, event)
            % Update Cpos and Rpos accordingly
            app.Cpos(3) = app.CenterYEditField.Value;
            Cpos2Rpos(app);
            updateTargetPos(app);
            
        end

        % Button pushed function: ONOFFButton
        function ONOFFButtonPushed(app, event)
            if app.targetVisible
                drawTargetOff(app);
                app.targetVisible = ~app.targetVisible; %switch to not visible

            else
                drawTargetOn(app);
                app.targetVisible = ~app.targetVisible; % switch to visible

            end
            
        end

        % Selection changed function: ProgramModeButtonGroup
        function ProgramModeButtonGroupSelectionChanged(app, event)
            selectedButton = app.ProgramModeButtonGroup.SelectedObject;
            
            switch selectedButton.Text
                case 'Run'
                    % first disable all configuration
                    app.TargetPositionSettingsPanel.Enable = 'off';
                    app.DisplayGraphSettingsPanel.Enable = 'off';
                    app.changeTargetColorButton.Enable = 'off';
                    
                    % enable the target toggle button
                    app.ONOFFButton.Enable = 'on';
                    
                    % make axes invisible
                    app.UIAxes.Visible = 'off';
                    app.dispAxes.Visible = 'off';
                    
                case 'Configure'
                    % first enable all configuration
                    app.TargetPositionSettingsPanel.Enable = 'on';
                    app.DisplayGraphSettingsPanel.Enable = 'on';
                    app.changeTargetColorButton.Enable = 'on';
                    
                    % disable the target toggle button
                    app.ONOFFButton.Enable = 'off';
                    
                    % make axes visible and print current target again
                    app.UIAxes.Visible = 'on';
                    app.dispAxes.Visible = 'on';
                    drawTargetOn(app);
            end
            
        end

        % Value changed function: XaxisMinEditField
        function XaxisMinEditFieldValueChanged(app, event)
            value = app.XaxisMinEditField.Value;
            app.UIAxes.XLim(1) = value;
            app.dispAxes.XLim(1) = value;           
            
        end

        % Value changed function: XaxisMaxEditField
        function XaxisMaxEditFieldValueChanged(app, event)
            value = app.XaxisMaxEditField.Value;
            app.UIAxes.XLim(2) = value;
            app.dispAxes.XLim(2) = value;
            
        end

        % Value changed function: YaxisMinEditField
        function YaxisMinEditFieldValueChanged(app, event)
            value = app.YaxisMinEditField.Value;
            app.UIAxes.YLim(1) = value;
            app.dispAxes.YLim(1) = value;
            
        end

        % Value changed function: YaxisMaxEditField
        function YaxisMaxEditFieldValueChanged(app, event)
            value = app.YaxisMaxEditField.Value;
            app.UIAxes.YLim(2) = value;
            app.dispAxes.YLim(2) = value;
            
        end

        % Button pushed function: changeTargetColorButton
        function changeTargetColorButtonPushed(app, event)
            % Disable button while dialog is open
            app.changeTargetColorButton.Enable = 'off';
            
            app.DialogApp = targetColorDialogBox(app);
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % delete both the manualTargetAppear app and the
            % targetColorDialogBox if it's open too
            delete(app.DialogApp);
            delete(app)
            close all
            
        end

        % Menu selected function: SavecurrentconfigtofileMenu
        function SavecurrentconfigtofileMenuSelected(app, event)
            % gather current configuration data and JSON encode it
            updateConfig(app)
            app.cfgJSON = jsonencode(app.cfg);
            
            % user navigates to directory and names config file, then it
            % saves there as .json (essentially a txt in json formatting)
            [file, path] = uiputfile('.json', 'Name the configuration file');
            fid = fopen([path file],'w');
            fprintf(fid, '%s', app.cfgJSON);
            fclose(fid);

            
        end

        % Menu selected function: LoadconfigfileMenu
        function LoadconfigfileMenuSelected(app, event)
            % user selects JSON file to load
            [file, path] = uigetfile('.json', 'Load configuration JSON file');
            txt = importdata([path file]);
            app.cfgJSON = txt{1};
            app.cfg = jsondecode(app.cfgJSON);
            
            % configuration data (cfg) gets applied to all relevant
            % variables and updated to all visible fields
            applyConfig(app);
            drawTargetOn(app);
            updateTargetColor(app, app.targetColor);
            updateTargetPos(app);
            
            % Figure properties
            app.UIFigure.Position = [app.cfg.UIFigure.Position.left, ...
                app.cfg.UIFigure.Position.bottom, ...
                app.cfg.UIFigure.Position.width, ...
                app.cfg.UIFigure.Position.height];
            app.dispFigure.Position = [app.cfg.dispFigure.Position.left, ...
                app.cfg.dispFigure.Position.bottom, ...
                app.cfg.dispFigure.Position.width, ...
                app.cfg.dispFigure.Position.height];
            
            % Axes properties
            app.UIAxes.XLim = [app.cfg.Axes.XLim.Min, app.cfg.Axes.XLim.Max];
            app.UIAxes.YLim = [app.cfg.Axes.YLim.Min, app.cfg.Axes.YLim.Max];
            app.dispAxes.XLim = [app.cfg.Axes.XLim.Min, app.cfg.Axes.XLim.Max];
            app.dispAxes.YLim = [app.cfg.Axes.YLim.Min, app.cfg.Axes.YLim.Max];
     
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadconfigfileMenu = uimenu(app.FileMenu);
            app.LoadconfigfileMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadconfigfileMenuSelected, true);
            app.LoadconfigfileMenu.Text = 'Load config file';
            app.SavecurrentconfigtofileMenu = uimenu(app.FileMenu);
            app.SavecurrentconfigtofileMenu.MenuSelectedFcn = createCallbackFcn(app, @SavecurrentconfigtofileMenuSelected, true);
            app.SavecurrentconfigtofileMenu.Text = 'Save current config to file';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Target Preview')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.TargetPositionSettingsPanel = uipanel(app.UIFigure);
            app.TargetPositionSettingsPanel.Title = 'Target Position Settings';
            app.DiameterEditFieldLabel = uilabel(app.TargetPositionSettingsPanel);
            app.DiameterEditFieldLabel.Text = 'Diameter';
            app.DiameterEditField = uieditfield(app.TargetPositionSettingsPanel, 'numeric');
            app.DiameterEditField.ValueChangedFcn = createCallbackFcn(app, @DiameterEditFieldValueChanged, true);
            app.DiameterEditField.Value = 2;
            app.CenterXEditFieldLabel = uilabel(app.TargetPositionSettingsPanel);
            app.CenterXEditFieldLabel.Text = 'Center-X';
            app.CenterXEditField = uieditfield(app.TargetPositionSettingsPanel, 'numeric');
            app.CenterXEditField.ValueChangedFcn = createCallbackFcn(app, @CenterXEditFieldValueChanged, true);
            app.CenterXEditField.Value = 5;
            app.CenterYEditFieldLabel = uilabel(app.TargetPositionSettingsPanel);
            app.CenterYEditFieldLabel.Text = 'Center-Y';
            app.CenterYEditField = uieditfield(app.TargetPositionSettingsPanel, 'numeric');
            app.CenterYEditField.ValueChangedFcn = createCallbackFcn(app, @CenterYEditFieldValueChanged, true);
            app.CenterYEditField.Value = 5;
            app.ProgramModeButtonGroup = uibuttongroup(app.UIFigure);
            app.ProgramModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ProgramModeButtonGroupSelectionChanged, true);
            app.ProgramModeButtonGroup.Title = 'Program Mode:';
            app.ConfigureButton = uitogglebutton(app.ProgramModeButtonGroup);
            app.ConfigureButton.Text = 'Configure';
            app.ConfigureButton.Value = true;
            app.RunButton_2 = uitogglebutton(app.ProgramModeButtonGroup);
            app.RunButton_2.Text = 'Run';
            app.ONOFFButton = uibutton(app.UIFigure, 'push');
            app.ONOFFButton.ButtonPushedFcn = createCallbackFcn(app, @ONOFFButtonPushed, true);
            app.ONOFFButton.Enable = 'off';
            app.ONOFFButton.Text = 'ON / OFF';
            app.TargetToggleLabel = uilabel(app.UIFigure);
            app.TargetToggleLabel.Text = 'Target Toggle';
            app.DisplayGraphSettingsPanel = uipanel(app.UIFigure);
            app.DisplayGraphSettingsPanel.Title = 'Display Graph Settings';
            app.XaxisMinEditField = uieditfield(app.DisplayGraphSettingsPanel, 'numeric');
            app.XaxisMinEditField.ValueChangedFcn = createCallbackFcn(app, @XaxisMinEditFieldValueChanged, true);
            app.XaxisMaxEditField = uieditfield(app.DisplayGraphSettingsPanel, 'numeric');
            app.XaxisMaxEditField.ValueChangedFcn = createCallbackFcn(app, @XaxisMaxEditFieldValueChanged, true);
            app.XaxisMaxEditField.Value = 10;
            app.YaxisMinEditField = uieditfield(app.DisplayGraphSettingsPanel, 'numeric');
            app.YaxisMinEditField.ValueChangedFcn = createCallbackFcn(app, @YaxisMinEditFieldValueChanged, true);
            app.YaxisMaxEditField = uieditfield(app.DisplayGraphSettingsPanel, 'numeric');
            app.YaxisMaxEditField.ValueChangedFcn = createCallbackFcn(app, @YaxisMaxEditFieldValueChanged, true);
            app.YaxisMaxEditField.Value = 10;
            app.MinLabel = uilabel(app.DisplayGraphSettingsPanel);
            app.MinLabel.Text = 'Min';
            app.MaxLabel = uilabel(app.DisplayGraphSettingsPanel);
            app.MaxLabel.Text = 'Max';
            app.XaxisLabel = uilabel(app.DisplayGraphSettingsPanel);
            app.XaxisLabel.Text = 'X-axis';
            app.YaxisLabel = uilabel(app.DisplayGraphSettingsPanel);
            app.YaxisLabel.Text = 'Y-axis';
            app.changeTargetColorButton = uibutton(app.UIFigure, 'push');
            app.changeTargetColorButton.ButtonPushedFcn = createCallbackFcn(app, @changeTargetColorButtonPushed, true);
            app.changeTargetColorButton.Text = 'change Target Color';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = manualTargetAppear

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end