classdef targetColorDialogBox < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MortgageCalculatorUIFigure  matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        SettargetcolorButton        matlab.ui.control.Button
        EdittargetcolorLabel        matlab.ui.control.Label
        GetcolorpresetDropDown      matlab.ui.control.DropDown
        GetcolorpresetLabel         matlab.ui.control.Label
        BLabel                      matlab.ui.control.Label
        GLabel                      matlab.ui.control.Label
        RLabel                      matlab.ui.control.Label
        BEditField                  matlab.ui.control.NumericEditField
        GEditField                  matlab.ui.control.NumericEditField
        REditField                  matlab.ui.control.NumericEditField
        RightPanel                  matlab.ui.container.Panel
        targetColorDispAxes         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
         targetPos =  [4 4 2 2]; % Description
         targetRGBcolor;
         R; % object for tracking properties of rectangle
         
        CallingApp % Main app object
        
    end
    
    methods (Access = private)
        
        
        function updateTargetColor(app)
            app.R.FaceColor = app.targetRGBcolor;
            app.R.EdgeColor = app.targetRGBcolor;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app object
            app.CallingApp = mainapp;
            
            % draw initial circle target; later changes to color should be
            % reflected in an update function
            app.targetRGBcolor = [app.REditField.Value, ...
                app.GEditField.Value, ...
                app.BEditField.Value];
            axis(app.targetColorDispAxes, 'square');
            grid(app.targetColorDispAxes, 'on')
            app.targetColorDispAxes.Visible = 'off';
            position = app.targetPos; % format: [x y w h]
            app.R = rectangle(app.targetColorDispAxes, 'Position', position, ...
                'Curvature', [1 1], ...
                'FaceColor', app.targetRGBcolor, ...
                'EdgeColor', app.targetRGBcolor);
            
            
        end

        % Value changed function: REditField
        function REditFieldValueChanged(app, event)
            app.targetRGBcolor(1) = app.REditField.Value;
            updateTargetColor(app)
            
        end

        % Value changed function: GEditField
        function GEditFieldValueChanged(app, event)
            app.targetRGBcolor(2) = app.GEditField.Value;
            updateTargetColor(app)
            
        end

        % Value changed function: BEditField
        function BEditFieldValueChanged(app, event)
            app.targetRGBcolor(3) = app.BEditField.Value;
            updateTargetColor(app)
            
        end

        % Value changed function: GetcolorpresetDropDown
        function GetcolorpresetDropDownValueChanged(app, event)
            colorText = app.GetcolorpresetDropDown.Value;
            
            switch colorText
                case 'red'
                    app.targetRGBcolor = [1 0 0];
                    app.REditField.Value = app.targetRGBcolor(1);
                    app.GEditField.Value = app.targetRGBcolor(2);
                    app.BEditField.Value = app.targetRGBcolor(3);
                    updateTargetColor(app);
                    
                case 'green'
                    app.targetRGBcolor = [0 1 0];
                    app.REditField.Value = app.targetRGBcolor(1);
                    app.GEditField.Value = app.targetRGBcolor(2);
                    app.BEditField.Value = app.targetRGBcolor(3);
                    updateTargetColor(app);
                    
                case 'blue'
                    app.targetRGBcolor = [0 0 1];
                    app.REditField.Value = app.targetRGBcolor(1);
                    app.GEditField.Value = app.targetRGBcolor(2);
                    app.BEditField.Value = app.targetRGBcolor(3);
                    updateTargetColor(app);
                    
                case 'cyan'
                    app.targetRGBcolor = [0 1 1];
                    app.REditField.Value = app.targetRGBcolor(1);
                    app.GEditField.Value = app.targetRGBcolor(2);
                    app.BEditField.Value = app.targetRGBcolor(3);
                    updateTargetColor(app);
                    
                case 'magenta'
                    app.targetRGBcolor = [1 0 1];
                    app.REditField.Value = app.targetRGBcolor(1);
                    app.GEditField.Value = app.targetRGBcolor(2);
                    app.BEditField.Value = app.targetRGBcolor(3);
                    updateTargetColor(app);
                    
                case 'yellow'
                    app.targetRGBcolor = [1 1 0];
                    app.REditField.Value = app.targetRGBcolor(1);
                    app.GEditField.Value = app.targetRGBcolor(2);
                    app.BEditField.Value = app.targetRGBcolor(3);
                    updateTargetColor(app);
                    
                case 'white'
                    app.targetRGBcolor = [1 1 1];
                    app.REditField.Value = app.targetRGBcolor(1);
                    app.GEditField.Value = app.targetRGBcolor(2);
                    app.BEditField.Value = app.targetRGBcolor(3);
                    updateTargetColor(app);
                    
            end
            
            
        end

        % Button pushed function: SettargetcolorButton
        function SettargetcolorButtonPushed(app, event)
            % Call main app's public function
            updateTargetColor(app.CallingApp, app.targetRGBcolor)
            
            % Delete the dialog box
            delete(app)
            
        end

        % Close request function: MortgageCalculatorUIFigure
        function MortgageCalculatorUIFigureCloseRequest(app, event)
            % Enable the edit target color button in the main app
            app.CallingApp.EditTargetColorButton.Enable = 'on';
            
            % delete the dialog box
            delete(app);
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.MortgageCalculatorUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {316, 316};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {257, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MortgageCalculatorUIFigure = uifigure('Visible', 'off');
            app.MortgageCalculatorUIFigure.Name = 'Mortgage Calculator';
            app.MortgageCalculatorUIFigure.CloseRequestFcn = createCallbackFcn(app, @MortgageCalculatorUIFigureCloseRequest, true);
            app.MortgageCalculatorUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.MortgageCalculatorUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.REditField = uieditfield(app.LeftPanel, 'numeric');
            app.REditField.Limits = [0 1];
            app.REditField.ValueChangedFcn = createCallbackFcn(app, @REditFieldValueChanged, true);
            app.REditField.Value = 1;
            app.GEditField = uieditfield(app.LeftPanel, 'numeric');
            app.GEditField.Limits = [0 1];
            app.GEditField.ValueChangedFcn = createCallbackFcn(app, @GEditFieldValueChanged, true);
            app.BEditField = uieditfield(app.LeftPanel, 'numeric');
            app.BEditField.Limits = [0 1];
            app.BEditField.ValueChangedFcn = createCallbackFcn(app, @BEditFieldValueChanged, true);
            app.RLabel = uilabel(app.LeftPanel);
            app.RLabel.Text = 'R';
            app.GLabel = uilabel(app.LeftPanel);
            app.GLabel.Text = 'G';
            app.BLabel = uilabel(app.LeftPanel);
            app.BLabel.Text = 'B';
            app.GetcolorpresetLabel = uilabel(app.LeftPanel);
            app.GetcolorpresetLabel.Text = 'Get color preset:';
            app.GetcolorpresetDropDown = uidropdown(app.LeftPanel);
            app.GetcolorpresetDropDown.Items = {'red', 'green', 'blue', 'cyan', 'magenta', 'yellow', 'white'};
            app.GetcolorpresetDropDown.ValueChangedFcn = createCallbackFcn(app, @GetcolorpresetDropDownValueChanged, true);
            app.GetcolorpresetDropDown.Value = 'red';
            app.EdittargetcolorLabel = uilabel(app.LeftPanel);
            app.EdittargetcolorLabel.Text = 'Edit target color';
            app.SettargetcolorButton = uibutton(app.LeftPanel, 'push');
            app.SettargetcolorButton.ButtonPushedFcn = createCallbackFcn(app, @SettargetcolorButtonPushed, true);
            app.SettargetcolorButton.Text = 'Set target color';
            app.RightPanel = uipanel(app.GridLayout);
            app.targetColorDispAxes = uiaxes(app.RightPanel);
            title(app.targetColorDispAxes, 'Target Preview')
            app.targetColorDispAxes.Visible = 'off';
            % Show the figure after all components are created
            app.MortgageCalculatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = targetColorDialogBox(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MortgageCalculatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MortgageCalculatorUIFigure)
        end
    end
end